classdef Bank < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BankUIFigure           matlab.ui.Figure
        WithdrowButton         matlab.ui.control.Button
        BalanceEditFieldLabel  matlab.ui.control.Label
        ViewBalance            matlab.ui.control.NumericEditField
        RMBEditFieldLabel      matlab.ui.control.Label
        ViewRMB                matlab.ui.control.NumericEditField
        DepositeButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        Balance double % Description
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Balance = 500;
            app.ViewRMB.Value = 0 ;
            app.ViewBalance.Value = app.Balance;
        end

        % Button pushed function: WithdrowButton
        function WithdrowButtonPushed(app, event)
            value = app.ViewRMB.Value ;
            app.Balance = app.Balance - value;
            app.ViewBalance.Value = app.Balance;
        end

        % Button pushed function: DepositeButton
        function DepositeButtonPushed(app, event)
            value = app.ViewRMB.Value ;
            app.Balance = app.Balance + value;
            app.ViewBalance.Value = app.Balance;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BankUIFigure = uifigure;
            app.BankUIFigure.Name = 'Bank';
            app.WithdrowButton = uibutton(app.BankUIFigure, 'push');
            app.WithdrowButton.ButtonPushedFcn = createCallbackFcn(app, @WithdrowButtonPushed, true);
            app.WithdrowButton.Text = 'Withdrow';
            app.BalanceEditFieldLabel = uilabel(app.BankUIFigure);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.ViewBalance = uieditfield(app.BankUIFigure, 'numeric');
            app.RMBEditFieldLabel = uilabel(app.BankUIFigure);
            app.RMBEditFieldLabel.Text = 'RMB';
            app.ViewRMB = uieditfield(app.BankUIFigure, 'numeric');
            app.DepositeButton = uibutton(app.BankUIFigure, 'push');
            app.DepositeButton.ButtonPushedFcn = createCallbackFcn(app, @DepositeButtonPushed, true);
            app.DepositeButton.Text = 'Deposite';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Bank

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BankUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BankUIFigure)
        end
    end
end