classdef Bank < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainPanel     matlab.ui.Figure
        btn_withdraw  matlab.ui.control.Button
        btn_deposit   matlab.ui.control.Button
        m1Label       matlab.ui.control.Label
        edt_m1        matlab.ui.control.NumericEditField
        m2Label       matlab.ui.control.Label
        edt_m2        matlab.ui.control.NumericEditField
        k1Label       matlab.ui.control.Label
        edt_k1        matlab.ui.control.NumericEditField
        k2Label       matlab.ui.control.Label
        edt_k2        matlab.ui.control.NumericEditField
        b1Label       matlab.ui.control.Label
        edt_b1        matlab.ui.control.NumericEditField
        b2Label       matlab.ui.control.Label
        edt_b2        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Balance double % Description
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Balance = 500;
            app.edt_m2.Value = 0 ;
            app.edt_m1.Value = app.Balance;
        end

        % Callback function
        function DepositeButtonPushed(app, event)
            value = app.edt_m2.Value ;
            app.Balance = app.Balance + value;
            app.edt_m1.Value = app.Balance;
        end

        % Callback function
        function WithdrowButtonPushed(app, event)
            value = app.edt_m2.Value ;
            app.Balance = app.Balance - value;
            app.edt_m1.Value = app.Balance;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainPanel = uifigure;
            app.MainPanel.Name = 'Suspension Designer';
            app.btn_withdraw = uibutton(app.MainPanel, 'push');
            app.btn_withdraw.ButtonPushedFcn = createCallbackFcn(app, @WithdrowButtonPushed, true);
            app.btn_withdraw.Text = 'Withdraw';
            app.btn_deposit = uibutton(app.MainPanel, 'push');
            app.btn_deposit.ButtonPushedFcn = createCallbackFcn(app, @DepositeButtonPushed, true);
            app.btn_deposit.Text = 'Deposit';
            app.m1Label = uilabel(app.MainPanel);
            app.m1Label.Text = 'm1';
            app.edt_m1 = uieditfield(app.MainPanel, 'numeric');
            app.m2Label = uilabel(app.MainPanel);
            app.m2Label.Text = 'm2';
            app.edt_m2 = uieditfield(app.MainPanel, 'numeric');
            app.k1Label = uilabel(app.MainPanel);
            app.k1Label.Text = 'k1';
            app.edt_k1 = uieditfield(app.MainPanel, 'numeric');
            app.k2Label = uilabel(app.MainPanel);
            app.k2Label.Text = 'k2';
            app.edt_k2 = uieditfield(app.MainPanel, 'numeric');
            app.b1Label = uilabel(app.MainPanel);
            app.b1Label.Text = 'b1';
            app.edt_b1 = uieditfield(app.MainPanel, 'numeric');
            app.b2Label = uilabel(app.MainPanel);
            app.b2Label.Text = 'b2';
            app.edt_b2 = uieditfield(app.MainPanel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Bank

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainPanel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainPanel)
        end
    end
end